classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        RadiusSlider               matlab.ui.control.Slider
        HeightSlider_2Label_2      matlab.ui.control.Label
        HeightSlider               matlab.ui.control.Slider
        HeightSlider_2Label        matlab.ui.control.Label
        WidthSlider                matlab.ui.control.Slider
        WidthSliderLabel           matlab.ui.control.Label
        ChooseaColorDropDown       matlab.ui.control.DropDown
        ChooseaColorDropDownLabel  matlab.ui.control.Label
        ChooseashapeButtonGroup    matlab.ui.container.ButtonGroup
        EquilateralTriangleButton  matlab.ui.control.RadioButton
        TriangleButton             matlab.ui.control.RadioButton
        CircleButton               matlab.ui.control.RadioButton
        RectangleButton            matlab.ui.control.RadioButton
        RightPanel                 matlab.ui.container.Panel
        EpicShapeCreatorc2023BryanZublinJustinMessickLabel  matlab.ui.control.Label
        GoButton                   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        shapeChoice
        color
        width
        height
        radius
        shape
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.shapeChoice = "";
            app.color = "";
            app.width = 0;
            app.height = 0;
            app.radius = 0;
        end

        % Selection changed function: ChooseashapeButtonGroup
        function ChooseashapeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseashapeButtonGroup.SelectedObject;
            app.shapeChoice = selectedButton.Text;
            disp(app.shapeChoice);
        end

        % Value changed function: WidthSlider
        function WidthSliderValueChanged(app, event)
            value = app.WidthSlider.Value;
            app.width = value;
        end

        % Value changed function: HeightSlider
        function HeightSliderValueChanged(app, event)
            value = app.HeightSlider.Value;
            app.height = value;
        end

        % Value changed function: RadiusSlider
        function RadiusSliderValueChanged(app, event)
            value = app.RadiusSlider.Value;
            app.radius = value;
        end

        % Value changed function: ChooseaColorDropDown
        function ChooseaColorDropDownValueChanged(app, event)
            value = app.ChooseaColorDropDown.Value;
            app.color = value;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            fprintf("%s %s %d %d",app.shapeChoice,app.color,app.width,app.height);
            disp(app.shapeChoice);
            switch app.shapeChoice
                case "Rectangle"
                    app.shape = Rectangle("Rectangle", app.width, app.height, app.color);
                    fprintf("%s %s %d %d",app.shapeChoice,app.color,app.width,app.height);
                case "Circle"
                    app.shape = Circle("Circle", app.radius, app.color);
                case "Triangle"
                    app.shape = Triangle("Triangle", app.width, app.height, app.color);
                case "EquilateralTriangle"
                    app.shape = EquilateralTriangle("Equilateral Triangle", app.width, app.color);
            end
            app.shape.Draw();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {311, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ChooseashapeButtonGroup = uibuttongroup(app.LeftPanel);
            app.ChooseashapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseashapeButtonGroupSelectionChanged, true);
            app.ChooseashapeButtonGroup.Title = 'Choose a shape';
            app.RectangleButton = uiradiobutton(app.ChooseashapeButtonGroup);
            app.RectangleButton.Text = 'Rectangle';
            app.RectangleButton.Value = true;
            app.CircleButton = uiradiobutton(app.ChooseashapeButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.TriangleButton = uiradiobutton(app.ChooseashapeButtonGroup);
            app.TriangleButton.Text = 'Triangle';
            app.EquilateralTriangleButton = uiradiobutton(app.ChooseashapeButtonGroup);
            app.EquilateralTriangleButton.Text = 'EquilateralTriangle';
            app.ChooseaColorDropDownLabel = uilabel(app.LeftPanel);
            app.ChooseaColorDropDownLabel.Text = 'Choose a Color';
            app.ChooseaColorDropDown = uidropdown(app.LeftPanel);
            app.ChooseaColorDropDown.Items = {'Red', 'Blue', 'Green', 'Yellow', 'Black'};
            app.ChooseaColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseaColorDropDownValueChanged, true);
            app.ChooseaColorDropDown.Value = 'Red';
            app.WidthSliderLabel = uilabel(app.LeftPanel);
            app.WidthSliderLabel.Text = 'Width';
            app.WidthSlider = uislider(app.LeftPanel);
            app.WidthSlider.ValueChangedFcn = createCallbackFcn(app, @WidthSliderValueChanged, true);
            app.HeightSlider_2Label = uilabel(app.LeftPanel);
            app.HeightSlider_2Label.Text = 'Height';
            app.HeightSlider = uislider(app.LeftPanel);
            app.HeightSlider.ValueChangedFcn = createCallbackFcn(app, @HeightSliderValueChanged, true);
            app.HeightSlider_2Label_2 = uilabel(app.LeftPanel);
            app.HeightSlider_2Label_2.Text = 'Radius';
            app.RadiusSlider = uislider(app.LeftPanel);
            app.RadiusSlider.ValueChangedFcn = createCallbackFcn(app, @RadiusSliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GoButton = uibutton(app.RightPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.EpicShapeCreatorc2023BryanZublinJustinMessickLabel = uilabel(app.RightPanel);
            app.EpicShapeCreatorc2023BryanZublinJustinMessickLabel.Text = {'Epic Shape Creator (c) 2023 '; 'Bryan Zublin & Justin Messick'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end